classdef game1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        InstructionsPanel       matlab.ui.container.Panel
        HowtoPlayLabel          matlab.ui.control.Label
        TextArea                matlab.ui.control.TextArea
        BacktoHomeButton        matlab.ui.control.Button
        PlayPanel               matlab.ui.container.Panel
        WordCountdownLabel      matlab.ui.control.Label
        SubmitButton            matlab.ui.control.Button
        EditField5              matlab.ui.control.EditField
        EditField4              matlab.ui.control.EditField
        EditField3              matlab.ui.control.EditField
        EditField2              matlab.ui.control.EditField
        EditField               matlab.ui.control.EditField
        Button5                 matlab.ui.control.Button
        Button4                 matlab.ui.control.Button
        Button3                 matlab.ui.control.Button
        Button2                 matlab.ui.control.Button
        Button                  matlab.ui.control.Button
        GameModePanel           matlab.ui.container.Panel
        BeginButton             matlab.ui.control.Button
        HowtoPlayButton         matlab.ui.control.Button
        HomeLabel               matlab.ui.control.Label
        WelcomePanel            matlab.ui.container.Panel
        CLPS0950March2025Label  matlab.ui.control.Label
        ByKiaraIbanezDiazandOliviaWangLabel  matlab.ui.control.Label
        Button_2                matlab.ui.control.Button
        WELCOMETOLabel          matlab.ui.control.Label
    end

    
    %edited by olivia March 17, 2025 12:11AM-1:27AM
    %objectives:
        %added comments denoted where kiara wrote code (all of it lol) 
        %did not debug buttons sorry kiara also i feel like i fucked up the
        %connection from the begin button to the play panel
        %add instructions page (completed...needs work on BacktoHome
        %button linking) 
        %add gif title to welcome page (completed) 

    %kiara
    properties (Access = private)
        DraggingTile  matlab.ui.control.Button
        WordList string
        Dictionary;
        CorrectWord;
        wordSlots matlab.ui.control.EditField
   
    end
    
    methods (Access = private)
        %kiara
        function startupFcn(app)
    % Initialize word slots
    app.wordSlots = [app.EditField, app.EditField2, app.EditField3, app.EditField4, app.EditField5];
    showPanel(app, app.WelcomePanel);
end
        %kiara
       function showPanel(app, panel)
            % Hide all panels
            app.WelcomePanel.Visible = 'off';
            app.GameModePanel.Visible = 'off';
            app.PlayPanel.Visible = 'off';
            app.InstructionsPanel.Visible = 'off';

            % Show the selected panel
            panel.Visible = 'on';
        end

        %Dictionary and Word Validation Code
        %kiara
        function loadDictionary(app)
            persistent dictionary;
            if isempty(dictionary)
                fid = fopen('words.txt');
                dictionary = textscan (fid, '%s');
                dictionary = dictionary {1};
                fclose(fid);
            end
            app.Dictionary = dictionary;
        end
        %kiara
         function checkWord(app)
            % Collect the text from each word slot
            app.wordSlots = [app.EditField, app.EditField2, app.EditField3, app.EditField4, app.EditField5];
            formedWord = '';

            % Loop through each slot and concatenate the text
            for i = 1:length(app.wordSlots)
                formedWord = [formedWord, app.wordSlots(i).Value];  % Concatenate the letter in the slot
            end

            % Check if the formed word is valid (you could add your own dictionary here)

            % Display a message based on whether the word is valid or not
           if length(formedWord) ~= 5
        uialert(app.UIFigure, 'Please use all 5 letters.', 'Incomplete Word');
        return;
    end
    
    % Convert to uppercase for consistent comparison
    formedWord = upper(formedWord);
    
    % Load the dictionary if not already loaded
    if isempty(app.Dictionary)
        app.loadDictionary();
    end
    
    % Check if the formed word is in the dictionary
    isInDictionary = any(strcmpi(formedWord, app.Dictionary));
    
    % Check if the formed word is an anagram of the correct word
    % (has the same letters, but possibly in a different order)
    isAnagram = isAnagramOf(formedWord, app.CorrectWord);
    
    % Determine the result
    if isInDictionary && isAnagram
        uialert(app.UIFigure, 'Congratulations! You found a valid word!', 'Success');
        % Here you could add code to keep track of found words
        % and update the game state
    elseif ~isInDictionary
        uialert(app.UIFigure, 'Word not found in dictionary.', 'Invalid Word');
    elseif ~isAnagram
        uialert(app.UIFigure, 'Word does not use the correct letters.', 'Invalid Word');
    end
    
    % Clear the input fields after checking
    for i = 1:length(app.wordSlots)
        app.wordSlots(i).Value = '';
    end
    
    % Helper function to check if two words are anagrams
    function result = isAnagramOf(word1, word2)
        % Sort the letters of both words and compare
        sortedWord1 = sort(word1);
        sortedWord2 = sort(word2);
        result = strcmp(sortedWord1, sortedWord2);
    end
        end

        % Function to check if the formed word is valid
         %kiara
        function [dictionary, isValid] = isValidWord(app)
    persistent dict;
    
    if isempty(dict)
        % Load the dictionary only once
        fid = fopen('words.txt');
        dict = textscan(fid, '%s');
        dict = dict{1};  % Extract the cell array from the textscan result
        fclose(fid);
    end
    
    dictionary = dict;  % Return the dictionary
    
    % You can check validity here if needed
    isValid = @(word) any(strcmpi(word, dictionary));  % Check if the word is in the list
end


        %Random Letters Code
        %kiara
        function generateRandomLetters(app)

    disp('Generating random letters');

    if isempty(app.Dictionary)
        app.loadDictionary();
    end
    
   fiveLetterWords = app.Dictionary(cellfun(@length, app.Dictionary) == 5);
    
    if isempty(fiveLetterWords)
        error('No 5-letter words found in the dictionary');
    end
    
    % Select a random 5-letter word
    randomIndex = randi(length(fiveLetterWords));
    selectedWord = upper(fiveLetterWords{randomIndex});
    
    % Store the correct word (unscrambled)
    app.CorrectWord = selectedWord;
    
    % Scramble the letters
    scrambledIndices = randperm(5);
    scrambledWord = selectedWord(scrambledIndices);
    
    % Ensure the scrambled word is different from the original word
    while strcmp(scrambledWord, selectedWord)
        scrambledIndices = randperm(5);
        scrambledWord = selectedWord(scrambledIndices);
    end
    
    % Set the text of the buttons to the scrambled letters
    app.Button.Text = scrambledWord(1);
    app.Button2.Text = scrambledWord(2);
    app.Button3.Text = scrambledWord(3);
    app.Button4.Text = scrambledWord(4);
    app.Button5.Text = scrambledWord(5);
    
    % For debugging - display words in console
    disp(['Original word: ', selectedWord]);
    disp(['Scrambled word: ', scrambledWord]);
    
    % Ensure UI updates immediately
    drawnow;
end


        % Drag and Drop Code
         %kiara
        function startTileDrag(app, src, ~)
             if isa(src, 'matlab.ui.control.Button')
        app.DraggingTile = src;
        
        % Store original position for return if needed
        %kiara
       if isempty(app.DraggingTile.UserData)
            app.DraggingTile.UserData = struct();
        end
        
        % Store original position for return if needed
        %kiara
        app.DraggingTile.UserData.originalPosition = app.DraggingTile.Position;
        
        % Set up event handlers
        %kiara
        set(app.UIFigure, 'WindowButtonMotionFcn', @(src,event)app.dragTile(event));
        set(app.UIFigure, 'WindowButtonUpFcn', @(src,event)app.dropTile(event));
        
        disp(['Dragging ', app.DraggingTile.Text]);
    else
        disp('Invalid drag source');
    end
        end
        
        %kiara
        function dragTile(app, ~)
           if ~isempty(app.DraggingTile)
        % Get current mouse position relative to the figure
        currentPoint = app.UIFigure.CurrentPoint;
        
        % Keep the tile size the same
        tileWidth = app.DraggingTile.Position(3);
        tileHeight = app.DraggingTile.Position(4);
        
        % Center the tile on the mouse cursor
        newX = currentPoint(1) - (tileWidth / 2);
        newY = currentPoint(2) - (tileHeight / 2);
        
        % Make sure the tile stays within the figure bounds
        figWidth = app.UIFigure.Position(3);
        figHeight = app.UIFigure.Position(4);
        
        newX = max(0, min(newX, figWidth - tileWidth));
        newY = max(0, min(newY, figHeight - tileHeight));
        
        % Update the tile position
        %kiara
        app.DraggingTile.Position = [newX, newY, tileWidth, tileHeight];
    end
        end

        function dropTile(app, ~)
            if isempty(app.DraggingTile)
                return;
            end
    
        dropPos = app.UIFigure.CurrentPoint;
    
            if any(isnan(dropPos)) || any(isinf(dropPos))
                disp('Invalid drop position');
                set(app.UIFigure, 'WindowButtonMotionFcn', '');
                set(app.UIFigure, 'WindowButtonUpFcn', '');
                return;
            end

            editFields = [app.EditField, app.EditField2, app.EditField3, app.EditField4, app.EditField5];
    
            placed = false;
            for i = 1:length(editFields)
                if app.isOverlapping(app.DraggingTile, editFields(i))

                    if ~isempty(editFields(i).Value)
                        disp(['Slot', num2str(i), 'is already occupied']);
                        continue;
                    end


                    slotPos = editFields(i).Position;
                    app.DraggingTile.Position(1) = slotPos(1);
                    app.DraggingTile.Position(2) = slotPos(2);
            
                    % Assign the value to the slot
                    editFields(i).Value = app.DraggingTile.Text;
                    placed = true;
                    disp(['Placed in slot ', num2str(i)]);
                    break;
                end
            end

            if ~placed
            % Return to original position if not placed
            %kiara
                if isfield(app.DraggingTile.UserData, 'originalPosition')
                    app.DraggingTile.Position = app.DraggingTile.UserData.originalPosition;
                end
                disp('Tile not placed in any valid slot');
            end
    
  
            % Clear event handlers
            %kiara
            set(app.UIFigure, 'WindowButtonMotionFcn', '');
            set(app.UIFigure, 'WindowButtonUpFcn', '');
    
            % Update UI
           
            drawnow;
          end
        %kiara
        function overlap = isOverlapping(app, tile, slot)
             tilePos = tile.Position;
             slotPos = slot.Position;

             tileCenter = [tilePos(1) + tilePos(3)/2, tilePos(2) + tilePos(4)/2];
             slotCenter = [slotPos(1) + slotPos(3)/2, slotPos(2) + slotPos(4)/2];

             distance = sqrt((tileCenter(1) - slotCenter(1))^2 + (tileCenter(2) - slotCenter(2))^2)

             overlap = (distance<30);

             if overlap
                 disp(['Tile center: (', num2str(tileCenter(1)), ',', num2str(tileCenter(2)), ')']);
                 disp(['Slot center: (', num2str(slotCenter(1)), ',', num2str(slotCenter(2)), ')']);
                 disp(['Distance: ', num2str(distance)]);
             end

           end
    function showGameModeScreen(app)
        % Hide the WelcomePanel and show the GameModePanel
        app.WelcomePanel.Visible = 'off';  % Hide WelcomePanel
        app.GameModePanel.Visible = 'on';  % Show GameModePanel
    end

    function showFiveNormalScreen(app)
        % Hide the GameModePanel and show the PlayPanel
        app.GameModePanel.Visible = 'off';  % Hide GameModePanel
        app.PlayPanel.Visible = 'on';       % Show PlayPanel (your game screen)
    end


    end    


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
 app.showPanel(app.GameModePanel);
        end

        % Button pushed function: HowtoPlayButton
        function HowtoPlayButtonPushed(app, event)
            app.showPanel(app.InstructionsPanel);

        end

        % Button pushed function: BeginButton
        function BeginButtonPushed(app, event)
            app.showPanel(app.PlayPanel);
            app.wordSlots = [app.EditField, app.EditField2, app.EditField3, app.EditField4, app.EditField5];
            app.showFiveNormalScreen();
            app.generateRandomLetters();

        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            app.checkWord();
        end

        % Button pushed function: Button, Button2, Button3, Button4, 
        % ...and 1 other component
        function LetterTilesClicked(app, event)
          app.startTileDrag(event.Source);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WelcomePanel = uipanel(app.UIFigure);
            app.WelcomePanel.Title = 'Welcome Panel';
            app.WELCOMETOLabel = uilabel(app.WelcomePanel);
            app.WELCOMETOLabel.Text = 'WELCOME TO';
            app.Button_2 = uibutton(app.WelcomePanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'anagram.gif');
            app.Button_2.Text = '';
            app.ByKiaraIbanezDiazandOliviaWangLabel = uilabel(app.WelcomePanel);
            app.ByKiaraIbanezDiazandOliviaWangLabel.Text = 'By Kiara Ibanez-Diaz and Olivia Wang';
            app.CLPS0950March2025Label = uilabel(app.WelcomePanel);
            app.CLPS0950March2025Label.Text = 'CLPS 0950 March 2025';
            app.GameModePanel = uipanel(app.UIFigure);
            app.GameModePanel.Title = 'GameModePanel';
            app.GameModePanel.Visible = 'off';
            app.HomeLabel = uilabel(app.GameModePanel);
            app.HomeLabel.Text = 'Home';
            app.HowtoPlayButton = uibutton(app.GameModePanel, 'push');
            app.HowtoPlayButton.ButtonPushedFcn = createCallbackFcn(app, @HowtoPlayButtonPushed, true);
            app.HowtoPlayButton.Text = 'How to Play';
            app.BeginButton = uibutton(app.GameModePanel, 'push');
            app.BeginButton.ButtonPushedFcn = createCallbackFcn(app, @BeginButtonPushed, true);
            app.BeginButton.Text = 'Begin';
            app.PlayPanel = uipanel(app.UIFigure);
            app.PlayPanel.Title = 'PlayPanel';
            app.PlayPanel.Visible = 'off';
            app.Button = uibutton(app.PlayPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @LetterTilesClicked, true);
            app.Button.Tag = 'LetterTiles';
            app.Button2 = uibutton(app.PlayPanel, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @LetterTilesClicked, true);
            app.Button2.Tag = 'LetterTiles';
            app.Button2.Text = 'Button2';
            app.Button3 = uibutton(app.PlayPanel, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @LetterTilesClicked, true);
            app.Button3.Tag = 'LetterTiles';
            app.Button3.Text = 'Button3';
            app.Button4 = uibutton(app.PlayPanel, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @LetterTilesClicked, true);
            app.Button4.Tag = 'LetterTiles';
            app.Button4.Text = 'Button4';
            app.Button5 = uibutton(app.PlayPanel, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @LetterTilesClicked, true);
            app.Button5.Tag = 'LetterTiles';
            app.Button5.Text = 'Button5';
            app.EditField = uieditfield(app.PlayPanel, 'text');
            app.EditField.Tag = 'WordSlots';
            app.EditField2 = uieditfield(app.PlayPanel, 'text');
            app.EditField2.Tag = 'WordSlots';
            app.EditField3 = uieditfield(app.PlayPanel, 'text');
            app.EditField3.Tag = 'WordSlots';
            app.EditField4 = uieditfield(app.PlayPanel, 'text');
            app.EditField4.Tag = 'WordSlots';
            app.EditField5 = uieditfield(app.PlayPanel, 'text');
            app.EditField5.Tag = 'WordSlots';
            app.SubmitButton = uibutton(app.PlayPanel, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'SubmitButton';
            app.WordCountdownLabel = uilabel(app.PlayPanel);
            app.WordCountdownLabel.Text = 'Word Countdown:';
            app.InstructionsPanel = uipanel(app.UIFigure);
            app.InstructionsPanel.Title = 'InstructionsPanel';
            app.InstructionsPanel.Visible = 'off';
            app.BacktoHomeButton = uibutton(app.InstructionsPanel, 'push');
            app.BacktoHomeButton.Text = 'Back to Home';
            app.TextArea = uitextarea(app.InstructionsPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Click and drag tiles from a series of scrambled letters. You will be given the count of possible valid words that can be created from rearranging the given letters. You must use all the tiles given. Once you enter a valid word, it will be added to a list on the side of your screen. Once you’ve entered all possible rearrangements of the letters, congratulations! You will be able to reset the game to generate another string of scrambled letters and play again! '};
            app.HowtoPlayLabel = uilabel(app.InstructionsPanel);
            app.HowtoPlayLabel.Text = 'How to Play';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = game1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end